classdef QuITO_graphicalInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField                      matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        ReadyLamp                      matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        BasisFunctionEditField         matlab.ui.control.NumericEditField
        BasisFunctionEditFieldLabel    matlab.ui.control.Label
        ShapeParameterDEditField       matlab.ui.control.NumericEditField
        ShapeParameterDEditFieldLabel  matlab.ui.control.Label
        NumberofStepsNEditField        matlab.ui.control.NumericEditField
        NumberofStepsNEditFieldLabel   matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        SelectExampleDropDown          matlab.ui.control.DropDown
        SelectExampleLabel             matlab.ui.control.Label
        QuITOGraphicalInterfaceLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Setting up and Importing Dependencies
            clc;      
            close all;
            
            % Set Ready status
            app.ReadyLampLabel.Text = 'Busy';
            app.ReadyLamp.Color = '#ff0000';
            drawnow;
            
            % Add the solver function to the path
            addpath('./src/problemTranscription')
            
            % Get user input values
            N = app.NumberofStepsNEditField.Value;
            D = app.ShapeParameterDEditField.Value;
            genFuncFlag = app.BasisFunctionEditField.Value;
            if floor(genFuncFlag) ~= genFuncFlag
                error('Basis Function is not an integer. Please provide an integer value between 1 and 6.')
            end                    
            
            % Adding paths to the file
            if strcmp(app.SelectExampleDropDown.Value, 'Aly Chan')
                addpath('./examples/Aly Chan')
                problem = AlyChan;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Bryson Denham')
                addpath('./examples/Bryson Denham')
                problem = BrysonDenham;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Double integrator tracking')
                addpath('./examples/Double integrator tracking')
                problem = DoubleIntTrack;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Inverted pendulum on a cart')
                addpath('./examples/Inverted pendulum on a cart')
                problem = invertedPendulumOnCart;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with control constraints)')
                addpath('./examples/Rayleigh problem (with control constraints)')
                problem = RayleighProblemControlConstraints;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
                addpath('./examples/Rayleigh problem (with mixed constraints)')
                problem = RayleighProblemMixedConstraint;
            elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
                addpath('./examples/Robot Path Planning')   
                problem = RobotPathPlanning;
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol control constrained')
                addpath('./examples/VanDerPol control constrained')
                problem = VanderPolControlConstraints;
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol state constrained')
                addpath('./examples/VanDerPol state constrained')
                problem = VanDerPol;
            elseif strcmp(app.SelectExampleDropDown.Value, 'User-defined template problem')
                addpath('./TemplateProblem/')
                problem = TemplateProblem;
            end

            %% Running the solve problem function for the selected problem   
            % Options for solving the problem
            opts = options(N,D,genFuncFlag);
            
            % Solving the problem
            if strcmp(app.SelectExampleDropDown.Value, 'Inverted pendulum on a cart')
                solution = solveInvertedPendulumOnCart(problem, opts);
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
                solution = solveRayleighProblemMixedConstraint(problem, opts);
            elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
                solution = solveRobotPathPlanning(problem, opts);
            else
                solution = solveProblem(problem, opts);
            end
            
            % Post Processing and plotting
            postProcess(solution, problem, opts);

            %% Remove the earlier added paths to avoid conflicts
            if strcmp(app.SelectExampleDropDown.Value, 'Aly Chan')
                rmpath('./examples/Aly Chan')               
            elseif strcmp(app.SelectExampleDropDown.Value, 'Bryson Denham')
                rmpath('./examples/Bryson Denham')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Double integrator tracking')
                rmpath('./examples/Double integrator tracking')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Inverted pendulum on a cart')
                rmpath('./examples/Inverted pendulum on a cart')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with control constraints)')
                rmpath('./examples/Rayleigh problem (with control constraints)')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
                rmpath('./examples/Rayleigh problem (with mixed constraints)')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
                rmpath('./examples/Robot Path Planning')   
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol control constrained')
                rmpath('./examples/VanDerPol control constrained')
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol state constrained')
                rmpath('./examples/VanDerPol state constrained')
            elseif strcmp(app.SelectExampleDropDown.Value, 'User-defined template problem')
                rmpath('./TemplateProblem/')
            end

            %% Updating the UI once the process is complete
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = '#00ff00';
            drawnow;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.QuITOGraphicalInterfaceLabel = uilabel(app.GridLayout);
            app.QuITOGraphicalInterfaceLabel.Text = 'QuITO: Graphical Interface';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectExampleLabel = uilabel(app.GridLayout2);
            app.SelectExampleLabel.Text = 'Select Example:';
            app.SelectExampleDropDown = uidropdown(app.GridLayout2);
            app.SelectExampleDropDown.Items = {'Aly Chan', 'Bryson Denham', 'Double integrator tracking', 'Inverted pendulum on a cart', 'Rayleigh problem (with control constraints)', 'Rayleigh problem (with mixed constraints)', 'Robot Path Planning', 'VanDerPol control constrained', 'VanDerPol state constrained', 'User-defined template problem'};
            app.SelectExampleDropDown.Value = 'Aly Chan';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NumberofStepsNEditFieldLabel = uilabel(app.GridLayout3);
            app.NumberofStepsNEditFieldLabel.Text = 'Number of Steps (N)';
            app.NumberofStepsNEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumberofStepsNEditField.Limits = [2 Inf];
            app.NumberofStepsNEditField.RoundFractionalValues = 'on';
            app.NumberofStepsNEditField.ValueDisplayFormat = '%.0f';
            app.NumberofStepsNEditField.Value = 100;
            app.ShapeParameterDEditFieldLabel = uilabel(app.GridLayout3);
            app.ShapeParameterDEditFieldLabel.Text = 'Shape Parameter (D)';
            app.ShapeParameterDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ShapeParameterDEditField.Limits = [0 Inf];
            app.ShapeParameterDEditField.Value = 2;
            app.BasisFunctionEditFieldLabel = uilabel(app.GridLayout3);
            app.BasisFunctionEditFieldLabel.Text = 'Basis Function';
            app.BasisFunctionEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BasisFunctionEditField.Limits = [1 6];
            app.BasisFunctionEditField.Value = 1;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = '208-2087614_green-transparent-play-button-hd-png-download.png';
            app.RunButton.Text = 'Run';
            app.ReadyLampLabel = uilabel(app.GridLayout4);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.GridLayout4);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuITO_graphicalInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end